classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NmerodepalavraserradasEditFieldLabel  matlab.ui.control.Label
        palavrasErradas            matlab.ui.control.NumericEditField
        ComputarButton             matlab.ui.control.Button
        ExecutarButton             matlab.ui.control.Button
        ProximoButton              matlab.ui.control.Button
        StatusBox                  matlab.ui.container.Panel
        udioemexecuoTextAreaLabel  matlab.ui.control.Label
        fraseExecucao              matlab.ui.control.TextArea
        StatusLampLabel            matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        VerdeudioexecutandoVermelhoOuvintepronunciandoLabel  matlab.ui.control.Label
        Canaisativos               matlab.ui.container.ButtonGroup
        Esquerda                   matlab.ui.control.RadioButton
        Direita                    matlab.ui.control.RadioButton
        EsquerdaDireita            matlab.ui.control.RadioButton
        Saida                      matlab.ui.container.ButtonGroup
        IC                         matlab.ui.control.RadioButton
        Normoouvintes              matlab.ui.control.RadioButton
        Pannel1                    matlab.ui.container.Panel
        AdicionarlistaButton       matlab.ui.control.Button
        listBoxOrdemExec           matlab.ui.control.ListBox
        nomeLista                  matlab.ui.control.Label
        FimButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        FolderFullPath % Description
        Resultados % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
        end

        % Button pushed function: AdicionarlistaButton
        function AdicionarlistaButtonPushed(app, event)
            % Seleciona pasta via UI e lê arquivos .WAV
            folderPath = uigetdir('','Selecione pasta com arquivos .wav da lista de sentenças.');
            cd(folderPath);
            addpath(genpath(folderPath));
            app.FolderFullPath = folderPath;
            listaAudios = dir('*.wav');
            
            % Trick para não minimizar app quando rodar uigetdir()
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Atualiza listBox com arquivos WAV da pasta selecionada
            app.listBoxOrdemExec.Items = {listaAudios.name};
            app.listBoxOrdemExec.ItemsData = 1:length({listaAudios.name});
            % Seleciona primeiro audio para execução
            app.listBoxOrdemExec.Value = 1;
            % Lê nome da pasta e plccxota na interface gráfica para controle
            [filepath,FolderName,ext] = fileparts(folderPath);
            app.nomeLista.Text = FolderName;
            
            % Cria vetor de resultados (adaptar para aplicação mais útil)
            app.Resultados = zeros(length({listaAudios.name}),1);
            
            % Atualiza permissoes botoes
            app.ExecutarButton.Enable = 'on';
            
            
        end

        % Button pushed function: ComputarButton
        function ComputarButtonPushed(app, event)
            % Habilita troca para próximo audio
            app.ProximoButton.Enable = 'on';
            app.Resultados(app.listBoxOrdemExec.Value) = app.palavrasErradas.Value;
            fprintf('Frases corretas para audio %s = %.0f \n',app.listBoxOrdemExec.Items{app.listBoxOrdemExec.Value},app.palavrasErradas.Value)            
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Carrega dados do arquivo
            fileName = app.listBoxOrdemExec.Items(app.listBoxOrdemExec.Value);
            fullFileName = fullfile(app.FolderFullPath,fileName);
                       
            % Le audio e metadata
            [signal, fs] = audioread(fullFileName{1});
            audioInformations = audioinfo(fullFileName{1});
            
            % Implementar metadata (fala completa) na seção 'Comment' do arquivo wav
            % Para isto: audiowrite(filename,y,fs,'Comment','frase por extenso')
            %app.fraseExecucao.Value = fileName; 
            % app.fraseExecucao.Value = audioInformations.Title; 
            app.fraseExecucao.Value = audioInformations.Title; 
            
            if app.Normoouvintes.Value == 1
                % Reproducao do sinal - A ser adaptada para comunicar com CCi-Mobile
                player = audioplayer(signal,fs);
                player.play         
                
            elseif app.IC.Value == 1
                
                % Call function 'sendStreamer(signal)' - ONLY WHEN HAVE INTERFACE
                % sendStreamer(fullFileName{1});                    
                disp('SENT TO IC!')                
            end
                        
            % Atualiza permissões e luz de status
            app.StatusLamp.Color = 'green';
            pause(length(signal)/fs) % intervalo para reprodução
            app.StatusLamp.Color = 'red';
            app.ComputarButton.Enable = 'on';
        end

        % Button pushed function: ProximoButton
        function ProximoButtonPushed(app, event)
            % Atualiza contador de lista
            app.listBoxOrdemExec.Value = app.listBoxOrdemExec.Value + 1;
            % Desabilita botão 'proximo audio' até reprodução do audio atual
            app.ProximoButton.Enable = 'off';
            app.ComputarButton.Enable = 'off';            
        end

        % Button pushed function: FimButton
        function FimButtonPushed(app, event)
            assignin ('base','resultadoTrial',app.Resultados);
            % Salva dados em .mat, na respectiva pasta dos audios aplicados
            save ResultsIntel
            app.delete
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.NmerodepalavraserradasEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodepalavraserradasEditFieldLabel.Text = 'Número de palavras erradas';
            app.palavrasErradas = uieditfield(app.UIFigure, 'numeric');
            app.ComputarButton = uibutton(app.UIFigure, 'push');
            app.ComputarButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarButtonPushed, true);
            app.ComputarButton.Enable = 'off';
            app.ComputarButton.Text = 'Computar';
            app.ExecutarButton = uibutton(app.UIFigure, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ProximoButton = uibutton(app.UIFigure, 'push');
            app.ProximoButton.ButtonPushedFcn = createCallbackFcn(app, @ProximoButtonPushed, true);
            app.ProximoButton.Enable = 'off';
            app.ProximoButton.Text = 'Próximo';
            app.StatusBox = uipanel(app.UIFigure);
            app.StatusBox.Title = 'Status de execução';
            app.udioemexecuoTextAreaLabel = uilabel(app.StatusBox);
            app.udioemexecuoTextAreaLabel.Text = 'Áudio em execução';
            app.fraseExecucao = uitextarea(app.StatusBox);
            app.StatusLampLabel = uilabel(app.StatusBox);
            app.StatusLampLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel = uilabel(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel.Text = {'Verde  - Áudio executando '; 'Vermelho   -  Ouvinte pronunciando'};
            app.Canaisativos = uibuttongroup(app.StatusBox);
            app.Canaisativos.Title = 'Canais ativos';
            app.Esquerda = uiradiobutton(app.Canaisativos);
            app.Esquerda.Text = 'Esquerda';
            app.Esquerda.Value = true;
            app.Direita = uiradiobutton(app.Canaisativos);
            app.Direita.Text = 'Direita';
            app.EsquerdaDireita = uiradiobutton(app.Canaisativos);
            app.EsquerdaDireita.Text = 'Esquerda / Direita';
            app.Saida = uibuttongroup(app.StatusBox);
            app.Saida.Title = 'Saida';
            app.IC = uiradiobutton(app.Saida);
            app.IC.Text = 'IC';
            app.IC.Value = true;
            app.Normoouvintes = uiradiobutton(app.Saida);
            app.Normoouvintes.Text = 'Normo-ouvintes';
            app.Pannel1 = uipanel(app.UIFigure);
            app.Pannel1.Title = 'Ordem de execução dos áudios';
            app.AdicionarlistaButton = uibutton(app.Pannel1, 'push');
            app.AdicionarlistaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarlistaButtonPushed, true);
            app.AdicionarlistaButton.Text = 'Adicionar lista';
            app.listBoxOrdemExec = uilistbox(app.Pannel1);
            app.listBoxOrdemExec.Items = {};
            app.listBoxOrdemExec.Enable = 'off';
            app.listBoxOrdemExec.Value = {};
            app.nomeLista = uilabel(app.Pannel1);
            app.nomeLista.Text = '';
            app.FimButton = uibutton(app.UIFigure, 'push');
            app.FimButton.ButtonPushedFcn = createCallbackFcn(app, @FimButtonPushed, true);
            app.FimButton.Text = 'Fim';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end